classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SAVEButton                  matlab.ui.control.Button
        ImageEnhancementandRestorationLabel  matlab.ui.control.Label
        PerformButton               matlab.ui.control.Button
        ChooseFillterDropDown       matlab.ui.control.DropDown
        ChooseFillterDropDownLabel  matlab.ui.control.Label
        UPLOUDIMAGEButton           matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img
        img1% Description
        img2 % Description
        img3
        img4

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOUDIMAGEButton
        function UPLOUDIMAGEButtonPushed(app, event)
           [file, path] = uigetfile('*.*');
           if isequal(file,0)
               figure(app.UIFigure)
               return;
           end
           figure(app.UIFigure)
           app.img=imread(fullfile(path,file));
           imshow(app.img,'Parent',app.UIAxes);
        end

        % Value changed function: ChooseFillterDropDown
        function ChooseFillterDropDownValueChanged(app, event)
            
            
        end

        % Button pushed function: PerformButton
        function PerformButtonPushed(app, event)
           
            switch app.ChooseFillterDropDown.Value
                case'Conver2Gray'
                    app.img2 = im2gray(app.img);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Convert2Black&White'
                    app.img2 = im2bw(app.img);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Bluring filter'
                    k=fspecial("average",[5,5]);
                    app.img2=imfilter(app.img,k);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Blue'
                    b=(app.img(:,:,3));
                    a=zeros(size(app.img,1),size(app.img,2));
                    app.img2=cat(3,a,a,b);
                    imshow(uint8(app.img2),'Parent',app.UIAxes_2);
                case'Convert2Indexed Image'
                    [app.img2,map]=rgb2ind(app.img,100);
                    imshow(app.img2,map,'Parent',app.UIAxes_2);
                case'Red'
                    red=app.img(:,:,1);
                    a=zeros(size(app.img,1),size(app.img,2));
                    app.img2=cat(3,red,a,a);
                    imshow(app.img2,'Parent',app.UIAxes_2);
               case'Green'
                    green=app.img(:,:,2);
                    a=zeros(size(app.img,1),size(app.img,2));
                    app.img2=cat(3,a,green,a);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'High resolution'
                    app.img2=imresize(app.img,2);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Low resolution'
                    app.img2=imresize(app.img,0.5);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Lightnes Image'
                    app.img2=imadd(app.img,50);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Darknes Image'
                    app.img2=imsubtract(app.img,50);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Complement'
                    app.img2=imcomplement(app.img);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Contrast Editing'
                    app.img2=imadjust(app.img,stretchlim(app.img),[]);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Solt&Paper removal'
                    %x=rgb2gray(app.img);
                    app.img2=medfilt3(app.img);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Solt noise removal'
                    app.img2=app.img;
                    for layer=1:3
                          app.img2(:,:,layer)=ordfilt2(app.img(:,:,layer),1,ones(3,3));
                     end
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Paper noise removal'
                    app.img2=app.img;
                    for layer=1:3
                          app.img2(:,:,layer)=ordfilt2(app.img(:,:,layer),9,ones(3,3));
                     end
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Sharpen filter'
%                     h=fspecial('laplacian',0.5);
%                     app.img3=imfilter(app.img,h);
%                     app.img2=app.img3+app.img;
                    k=fspecial("average",[5,5]);
                    f=imfilter(app.img,k);
                    app.img2=app.img+4*(app.img-f);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Logarithm'
                    h=fspecial("log");
                    app.img3=imfilter(app.img,h);
                    app.img2=app.img3+app.img;
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Specle noise removal'
                    app.img2=app.img;
                    for layer=1:3
                       app.img2(:,:,layer) = wiener2(app.img(:,:,layer), [9 9]);
                   end
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Histogram'
                    histogram(app.UIAxes_2,app.img)
                case'Adding Solt&Paper noise'
                    app.img2 = imnoise(app.img,'salt & pepper');
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Adding gaussian noise'
                    app.img2 = imnoise(app.img,'gaussian');
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Adding speckle noise'
                    app.img2 = imnoise(app.img,'speckle');
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Gaussian noise removal'
                     app.img2=app.img;
                    for layer=1:3
                       app.img2(:,:,layer) = wiener2(app.img(:,:,layer), [9 9]);
                   end
                    imshow(app.img2,'Parent',app.UIAxes_2);
                case'Equalization'
                    app.img2=histeq(app.img);
                    imshow(app.img2,'Parent',app.UIAxes_2);
                
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            imsave(app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Enhsnced Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.Visible = 'off';
            app.UPLOUDIMAGEButton = uibutton(app.UIFigure, 'push');
            app.UPLOUDIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOUDIMAGEButtonPushed, true);
            app.UPLOUDIMAGEButton.Text = 'UPLOUD IMAGE';
            app.ChooseFillterDropDownLabel = uilabel(app.UIFigure);
            app.ChooseFillterDropDownLabel.Text = 'Choose Fillter';
            app.ChooseFillterDropDown = uidropdown(app.UIFigure);
            app.ChooseFillterDropDown.Items = {'Conver2Gray', 'Convert2Black&White', 'Convert2Indexed Image', 'Blue', 'Red', 'Green', 'High resolution', 'Low resolution', 'Histogram', 'Bluring filter', 'Sharpen filter', 'Complement', 'Logarithm', 'Contrast Editing', 'Equalization', 'Darknes Image', 'Lightnes Image', 'Paper noise removal', 'Solt noise removal', 'Adding Solt&Paper noise', 'Adding gaussian noise', 'Adding speckle noise', 'Solt&Paper removal', 'Gaussian noise removal', 'Specle noise removal'};
            app.ChooseFillterDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseFillterDropDownValueChanged, true);
            app.ChooseFillterDropDown.Value = 'Conver2Gray';
            app.PerformButton = uibutton(app.UIFigure, 'push');
            app.PerformButton.ButtonPushedFcn = createCallbackFcn(app, @PerformButtonPushed, true);
            app.PerformButton.Text = 'Perform';
            app.ImageEnhancementandRestorationLabel = uilabel(app.UIFigure);
            app.ImageEnhancementandRestorationLabel.Text = 'Image Enhancement and Restoration';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end